classdef FourierSeries_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FreyaUIFigure                   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ClearButton                     matlab.ui.control.Button
        NumberofFunctionsSpinner        matlab.ui.control.Spinner
        NumberofFunctionsSpinnerLabel   matlab.ui.control.Label
        ConfirmButton                   matlab.ui.control.Button
        SelectedFunctionDropDown        matlab.ui.control.DropDown
        SelectedFunctionDropDownLabel   matlab.ui.control.Label
        SeriesCoefficientsDropDown      matlab.ui.control.DropDown
        SeriesCoefficientsDropDownLabel  matlab.ui.control.Label
        DeleteFunctionButton            matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        FunctionSegmentPanel            matlab.ui.container.Panel
        FunctionRuleImage               matlab.ui.control.Image
        AddButton                       matlab.ui.control.Button
        FunctionxEditField              matlab.ui.control.EditField
        FunctionxEditFieldLabel         matlab.ui.control.Label
        UpperLimitEditField             matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel        matlab.ui.control.Label
        LowerLimitEditField             matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel        matlab.ui.control.Label
        IntervalLengthEditField         matlab.ui.control.NumericEditField
        IntervalLengthEditFieldLabel    matlab.ui.control.Label
        PeriodsSpinner                  matlab.ui.control.Spinner
        HowmanyperiodsdoyouwanttoseeSpinnerLabel  matlab.ui.control.Label
        PeriodicLamp                    matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        IsPeriodicSwitch                matlab.ui.control.Switch
        IsaperiodicfunctionSwitchLabel  matlab.ui.control.Label
        OrthogonalExpansionDropDown     matlab.ui.control.DropDown
        OrthogonalExpansionLabel        matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        NumberOfTermsSlider             matlab.ui.control.Slider
        NumberofTermsLabel              matlab.ui.control.Label
        CoefficientsAxes                matlab.ui.control.UIAxes
        FourierSeriesAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        isPeriodic = 0;
        numberOfSegments;
        numberOfPeriods = 1;
        intervalLength = 0;
        originalFunctionDomain = [];
        originalFunctionValue = [];
        functionDomain = [];
        functionValue = [];
        functionExpansionValue = [];
        functionsMatrix = struct('lowerLimit', 0, 'upperLimit', 0, 'functionDef', 0);
        N = 0;
        functionGenerated = false;
        a0 = [];
        an = [];
        bn = [];
        w0 = 0;
        legendreCoefficients = [];
        besselCoefficients = [];
        numberOfFunctions = 1;
        selectedIndexFunction = 1;
        selectedOrthogonalExpansion;
        selectedSeriesCoefficients;
    end
    
    methods (Access = private) 
        
        function currentDir = getcurrentdir(app)
           %if isdeployed % Stand-alone mode.
           %   [status, result] = system('path');
           %   currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
           %else % MATLAB mode.
           %   currentDir = pwd;
           %end
           currentDir = fullfile(getenv('APPDATA'), 'Freya', 'Images');
        end
        
        function  [expansion, a0, an, bn] = getFourierSeriesExpansion(app)
  
            nPoints = length(app.originalFunctionDomain);
            
            C0 = (1 / 2) * ones(nPoints, 1);
            C = zeros(nPoints, app.N(app.selectedIndexFunction));
            S = zeros(nPoints, app.N(app.selectedIndexFunction));
            
            for n = 1:app.N(app.selectedIndexFunction)
                C(:, n) = cos(n * app.w0(app.selectedIndexFunction) * app.originalFunctionDomain);
                S(:, n) = sin(n * app.w0(app.selectedIndexFunction) * app.originalFunctionDomain);
            end
            
            a0 = 4 * trapz(app.originalFunctionDomain, app.originalFunctionValue .* C0, 1)'/ app.intervalLength(app.selectedIndexFunction);
            an = 2 * trapz(app.originalFunctionDomain, app.originalFunctionValue .* C, 1)' / app.intervalLength(app.selectedIndexFunction);
            bn = 2 * trapz(app.originalFunctionDomain, app.originalFunctionValue .* S, 1)' / app.intervalLength(app.selectedIndexFunction);
  
            if abs(a0) < 10E-15
                a0 = 0;
            end
            
            if ~isempty(an) && length(an) > 1 && all(abs(abs(an) - abs(an(1))) < 10E-15)
                an = zeros(length(an), 1);
            end

            bn(abs(bn) < 10E-15) = 0;
            expansion = C0 * a0 + C * an + S * bn;
                        
            FS = expansion * ones(1, app.numberOfPeriods(app.selectedIndexFunction));
            expansion = FS(:);
        end
        
        function [expansion, cn] = getFourierLegendreSeriesExpansion(app)
      
            P = legP(app.N(app.selectedIndexFunction), app.functionDomain, app.functionDomain(1), app.functionDomain(end), 1);
            %cn = (2 / (app.functionDomain(end) - app.functionDomain(1))) * trapz(app.functionDomain, app.functionValue .* P, 1);
            cn =  trapz(app.functionDomain, app.functionValue .* P, 1);
            cn(abs(cn) < 10E-15) = 0;
            expansion = P * cn';
            %cn = cn./sqrt(2./(2*(0:app.N(app.selectedIndexFunction))+1));
        end
        
        function [expansion, cn] = getFourierBesselSeriesExpansion(app)
              nPoints = length(app.functionDomain);
              v = 0;
              alpha = besselzero(v, app.N(app.selectedIndexFunction) + 1);
              w = alpha / app.functionDomain(end);
              B = zeros(nPoints, app.N(app.selectedIndexFunction) + 1);
              for n = 1:app.N(app.selectedIndexFunction) + 1
                  B(:, n) = besselj(v, w(n) * app.functionDomain);
              end
              cn = (trapz(app.functionDomain, app.functionDomain .* app.functionValue .* B, 1) ./ ...
                   trapz(app.functionDomain, app.functionDomain .* B .* B, 1))';
              cn(abs(cn) < 10E-15) = 0;
              expansion = B * cn;
        end
        
        function [expansion, cn] = getComplexFourierSeriesExpansion(app)
            
            nPoints = length(app.originalFunctionDomain);
            n = -app.N(app.selectedIndexFunction):app.N(app.selectedIndexFunction);
            E = zeros(nPoints, length(n));
            
            for i = 1:length(n)
                E(:, i) = exp(1i * n(i) * app.w0(app.selectedIndexFunction) * app.originalFunctionDomain);
            end
            
            cn = trapz(app.originalFunctionDomain, app.originalFunctionValue .* E, 1)' / app.intervalLength(app.selectedIndexFunction);
            expansion = E * cn;
            
            CFS = expansion * ones(1, app.numberOfPeriods(app.selectedIndexFunction));
            expansion = CFS(:);
        end
        
        function buildFunction(app)
            
            allFunctionValues = zeros(10000, 1);
            domain = zeros(10000, 1);
            lastNumberOfPoints = 0;
            nPoints = 1024;
            
            for i = 1:app.numberOfSegments(app.selectedIndexFunction)
       
                currentDomain = linspace(app.functionsMatrix(app.selectedIndexFunction, i).lowerLimit, app.functionsMatrix(app.selectedIndexFunction, i).upperLimit, nPoints);
                currentFunctionValues = app.functionsMatrix(app.selectedIndexFunction, i).functionDef(currentDomain);
                
                numberOfPoints = length(currentFunctionValues);
                
                indexes = lastNumberOfPoints * (i - 1) + 1:lastNumberOfPoints * (i - 1) + numberOfPoints;
                
                domain(indexes) = currentDomain;
                allFunctionValues(indexes) = currentFunctionValues;
                
                lastNumberOfPoints = numberOfPoints;
            end

            % Remove a porção não utilizada dos arrays
            domain(nPoints * app.numberOfSegments(app.selectedIndexFunction) + 1:end) = [];
            allFunctionValues(nPoints * app.numberOfSegments(app.selectedIndexFunction) + 1:end) = [];
            
            app.originalFunctionDomain = domain;
            app.originalFunctionValue = allFunctionValues;
            
            app.functionDomain = app.originalFunctionDomain;
            app.functionValue = app.originalFunctionValue;
            
            if app.isPeriodic(app.selectedIndexFunction)
                app.buildPeriodicFunction;
            end
        end
        
        function buildPeriodicFunction(app)
            
            D = app.originalFunctionDomain * ones(1, app.numberOfPeriods(app.selectedIndexFunction));
            
            for k = 2:app.numberOfPeriods(app.selectedIndexFunction)
                D(:, k) = D(:, k) + (k - 1) * app.intervalLength(app.selectedIndexFunction);
            end
            
            F = app.originalFunctionValue * ones(1, app.numberOfPeriods(app.selectedIndexFunction));
            
            app.functionDomain = D(:);
            app.functionValue = F(:);

        end
        
        function buildOrthogonalExpansion(app)
            % Determina qual expansão deve ser feita
            % com base na escolha do usuário
            switch str2double(app.selectedOrthogonalExpansion(app.selectedIndexFunction))
                case OrthogonalExpansions.FourierSeries
                    [app.functionExpansionValue, app.a0, app.an, app.bn] = app.getFourierSeriesExpansion;
                case OrthogonalExpansions.FourierLegendreSeries
                    [app.functionExpansionValue, app.legendreCoefficients] = app.getFourierLegendreSeriesExpansion;
                case OrthogonalExpansions.FourierBesselSeries
                    [app.functionExpansionValue, app.besselCoefficients] = app.getFourierBesselSeriesExpansion;
            end
        end
        
        function [amplitude, phase] = getUnilateralSpectrum(app)
            complexCoefficients = app.an - 1i * app.bn;
            
            amplitude = [app.a0/2; abs(complexCoefficients)];
            
            phase = rad2deg([(app.a0 < 0) * pi; atan2(imag(complexCoefficients), real(complexCoefficients))]);
            phase(phase > -180 & phase < -90) = mod(phase(phase < -90 & phase > -180), 360);
        end
        
        function [amplitude, phase] = getBilateralSpectrum(app)
            complexCoefficients = (app.an - 1i * app.bn)/2;
            
            amplitude = abs(complexCoefficients);
            amplitude = [flipud(amplitude); abs(app.a0/2); amplitude];
            
            phase = atan2(imag(complexCoefficients), real(complexCoefficients));
            phase = rad2deg([-flipud(phase); (app.a0 < 0) * pi; phase]);
        end

        function plotLegendreCoefficients(app)
            app.CoefficientsAxes.cla;
            set(app.CoefficientsAxes, 'XTickMode', 'auto', 'XTickLabelMode', 'auto');
            set(app.CoefficientsAxes, 'YTickMode', 'auto', 'YTickLabelMode', 'auto');
            set(app.CoefficientsAxes, 'ZTickMode', 'auto', 'ZTickLabelMode', 'auto');
            stem(app.CoefficientsAxes, 0:app.N(app.selectedIndexFunction), app.legendreCoefficients, 'filled', 'MarkerSize', 6, 'Color', 'b');
            xlabel(app.CoefficientsAxes, '$n$', 'FontSize', 16, 'interpreter', 'latex');
            ylabel(app.CoefficientsAxes, '$c_n$', 'FontSize', 16, 'interpreter', 'latex');
            title(app.CoefficientsAxes, 'Legendre Coefficients');
            if app.N(app.selectedIndexFunction) <= 20
                step = 1;
            elseif app.N(app.selectedIndexFunction) <= 50
                step = 5;
            else
                step = 10;
            end
            xticks(app.CoefficientsAxes, 0:step:app.N(app.selectedIndexFunction));
            xticklabels(app.CoefficientsAxes, string(0:step:app.N(app.selectedIndexFunction)));
            view(app.CoefficientsAxes, 2);
        end
        
        function plotBesselCoefficients(app)
            app.CoefficientsAxes.cla;
            set(app.CoefficientsAxes, 'XTickMode', 'auto', 'XTickLabelMode', 'auto');
            set(app.CoefficientsAxes, 'YTickMode', 'auto', 'YTickLabelMode', 'auto');
            set(app.CoefficientsAxes, 'ZTickMode', 'auto', 'ZTickLabelMode', 'auto');
            stem(app.CoefficientsAxes, 0:app.N(app.selectedIndexFunction), app.besselCoefficients, 'filled', 'MarkerSize', 6, 'Color', 'b');
            xlabel(app.CoefficientsAxes, '$n$', 'FontSize', 16, 'interpreter', 'latex');
            ylabel(app.CoefficientsAxes, '$c_n$', 'FontSize', 16, 'interpreter', 'latex');
            title(app.CoefficientsAxes, 'Bessel Coefficients');
            if app.N(app.selectedIndexFunction) <= 20
                step = 1;
            elseif app.N(app.selectedIndexFunction) <= 50
                step = 5;
            else
                step = 10;
            end
            xticks(app.CoefficientsAxes, 0:step:app.N(app.selectedIndexFunction));
            xticklabels(app.CoefficientsAxes, string(0:step:app.N(app.selectedIndexFunction)));
            view(app.CoefficientsAxes, 2);
        end
        
        function plotFunction(app)   
            
            % Desenha o gráfico da função inserida pelo usuário
            plot(app.FourierSeriesAxes, app.functionDomain, app.functionValue, 'r');   
            hold(app.FourierSeriesAxes, 'on');
           
            % Determina a expansão escolhida pelo usuário
            fourierSeriesType = '';
            switch str2double(app.selectedOrthogonalExpansion(app.selectedIndexFunction))
                case OrthogonalExpansions.FourierSeries
                    fourierSeriesType = 'Fourier Series';
                case OrthogonalExpansions.FourierLegendreSeries
                    fourierSeriesType = 'Fourier-Legendre Series';
                case OrthogonalExpansions.FourierBesselSeries
                    fourierSeriesType = 'Fourier-Bessel Series';
            end
            plotTitle = strjoin([fourierSeriesType, 'with', string(app.N(app.selectedIndexFunction)), 'terms']);
            
            % Desenha o gráfico da expansão em série da função inserida pelo
            % usuário
            plot(app.FourierSeriesAxes, app.functionDomain, app.functionExpansionValue, 'b');
            xlabel(app.FourierSeriesAxes, '$x$', 'Interpreter', 'latex');
            ylabel(app.FourierSeriesAxes, '$f(x)$', 'Interpreter', 'latex');
            title(app.FourierSeriesAxes, plotTitle);
            legend(app.FourierSeriesAxes, 'Original function', fourierSeriesType);
            hold(app.FourierSeriesAxes, 'off');
        end
        
        function plotSpectrum(app)
            
            % Limpa o gráfico dos coeficientes
            app.clearAxes(1);
            
            % Determina o tipo de expansão ortogonal e os coeficientes que
            % devem ser mostrados no gráficos
            switch str2double(app.selectedOrthogonalExpansion(app.selectedIndexFunction))
                case OrthogonalExpansions.FourierSeries
                    switch app.selectedSeriesCoefficients(app.selectedIndexFunction)
                        case '1'
                            app.plotUnilateralSpectrum(1);
                        case '2'
                            app.plot3DUnilateralSpectrum;
                        case '3'
                            app.plotUnilateralSpectrum(0);
                        case '4'
                            app.plotBilateralSpectrum(1);
                        case '5'
                            app.plotBilateralSpectrum(0);
                    end
                case OrthogonalExpansions.FourierLegendreSeries
                    switch app.selectedSeriesCoefficients(app.selectedIndexFunction)
                        case '1'
                            app.plotLegendreCoefficients;
                        %case '2'
                        %    app.plot3DLegendreCoefficients;
                    end
                    
                case OrthogonalExpansions.FourierBesselSeries
                    switch app.selectedSeriesCoefficients(app.selectedIndexFunction)
                        case '1'
                            app.plotBesselCoefficients;
                        %case '2'
                        %    app.plot3DBesselCoefficients;
                    end
            end
        end
        
        function configureOrthogonalExpansionDropDown(app) 
             
             switch str2double(app.selectedOrthogonalExpansion(app.selectedIndexFunction))
                case OrthogonalExpansions.FourierSeries
                    app.SeriesCoefficientsDropDown.Items = ...
                    {   'Unilateral Amplitude Spectrum', ...
                        'Unilateral Amplitude Spectrum (3D)', ...
                        'Unilateral Phase Spectrum', ...
                        'Bilateral Amplitude Spectrum', ...
                        'Bilateral Phase Spectrum'
                    };
                    app.SeriesCoefficientsDropDown.ItemsData = {'1', '2', '3', '4', '5'};
                    
                case OrthogonalExpansions.FourierLegendreSeries
                    %app.SeriesCoefficientsDropDown.Items = {'Legendre Coefficients', '3D Legendre Coefficients'};
                    %app.SeriesCoefficientsDropDown.ItemsData = {'1', '2'};
                    app.SeriesCoefficientsDropDown.Items = {'Legendre Coefficients'};
                    app.SeriesCoefficientsDropDown.ItemsData = {'1'};
                case OrthogonalExpansions.FourierBesselSeries
                    %app.SeriesCoefficientsDropDown.Items = {'Bessel Coefficients', '3D Bessel Coefficients'};
                    %app.SeriesCoefficientsDropDown.ItemsData = {'1', '2'};
                    app.SeriesCoefficientsDropDown.Items = {'Bessel Coefficients'};
                    app.SeriesCoefficientsDropDown.ItemsData = {'1'};
             end
        end
        
        function clearAxes(app, value)
            if value == 0
                % Limpa o gráfico da expansão ortogonal
                cla(app.FourierSeriesAxes)
               % app.FourierSeriesAxes.cla;
                set(app.FourierSeriesAxes, 'XTickMode', 'auto', 'XTickLabelMode', 'auto');
                set(app.FourierSeriesAxes, 'YTickMode', 'auto', 'YTickLabelMode', 'auto');
                set(app.FourierSeriesAxes, 'ZTickMode', 'auto', 'ZTickLabelMode', 'auto');
                xlabel(app.FourierSeriesAxes, '');
                ylabel(app.FourierSeriesAxes, '');
                title(app.FourierSeriesAxes, '');          
                legend(app.FourierSeriesAxes, 'off');
            elseif value == 1
                %app.CoefficientsAxes.cla;
                cla(app.FourierSeriesAxes)
                set(app.CoefficientsAxes, 'XTickMode', 'auto', 'XTickLabelMode', 'auto');
                set(app.CoefficientsAxes, 'YTickMode', 'auto', 'YTickLabelMode', 'auto');
                set(app.CoefficientsAxes, 'ZTickMode', 'auto', 'ZTickLabelMode', 'auto');
                xlabel(app.CoefficientsAxes, '');
                ylabel(app.CoefficientsAxes, '');
                zlabel(app.CoefficientsAxes, '');
                title(app.CoefficientsAxes, '');
                view(app.CoefficientsAxes, 2);
            end
        end
        
        function clearCurrentFunction(app)
            % Retorna as variáveis associadas a função selecionada para o
            % valor inicial padrão
            app.functionsMatrix(app.selectedIndexFunction, 1:end) = struct('lowerLimit', [], 'upperLimit', [], 'functionDef', []);
            app.numberOfSegments(app.selectedIndexFunction) = 0;
            app.intervalLength(app.selectedIndexFunction) = 0;
            app.w0(app.selectedIndexFunction) = 0; 
            app.N(app.selectedIndexFunction) = 0; 
            app.numberOfPeriods(app.selectedIndexFunction) = 1; 
            app.isPeriodic(app.selectedIndexFunction) = 0; 
            app.selectedOrthogonalExpansion(app.selectedIndexFunction) = repmat("1", 1, 1);
            app.selectedSeriesCoefficients(app.selectedIndexFunction) = repmat("1", 1, 1);
            app.functionGenerated(app.selectedIndexFunction) = 0;
            
            % Configura os menus suspensos da expansão ortogonal e dos
            % coeficientes
            app.OrthogonalExpansionDropDown.Value = char(app.selectedOrthogonalExpansion(app.selectedIndexFunction));
            app.configureOrthogonalExpansionDropDown;
            app.SeriesCoefficientsDropDown.Value = char(app.selectedSeriesCoefficients(app.selectedIndexFunction));
            
            % Habilita a caixa de texto do comprimento do intervalo e o
            % botão aplicar
            app.IntervalLengthEditFieldLabel.Enable = true;
            app.IntervalLengthEditField.Enable = true;
            app.IntervalLengthEditField.Value = 0;
            app.ApplyButton.Enable = true;
            
            % Configura a tela para o caso de uma função não periódica (por
            % padrão)
            app.IsPeriodicSwitch.Value = 'No';
            app.PeriodicLamp.Color = 'red';
            app.IntervalLengthEditFieldLabel.Text = 'Interval Length';
            app.DeleteFunctionButton.Enable = true;
            app.HowmanyperiodsdoyouwanttoseeSpinnerLabel.Enable = false;
            app.PeriodsSpinner.Enable = false;
            app.PeriodsSpinner.Value = 1;
            
            % Desabilita e limpa todos os objetos do painel que configura os
            % segmentos da função
            set(findall(app.FunctionSegmentPanel, '-property', 'enable'), 'enable', 'off');
            app.LowerLimitEditField.Value = 0;
            app.UpperLimitEditField.Value = 0;
            app.FunctionxEditField.Value = '';
            
            % Limpa a imagem com a regra da função
            app.FunctionRuleImage.ImageSource = '';
              
            % Desabilita o objeto que contrla o número de termos da
            % expansão
            app.NumberOfTermsSlider.Enable = false;
            app.NumberOfTermsSlider.Value = 0;
            
            % Limpa todos os gráficos
            app.clearAxes(0);
            app.clearAxes(1);
        end
        
        function loadSelectedFunction(app)
            
            % Carrega o tipo de expansão ortogonal e os coeficientes escolhidos pelo usuário
            % para a função selecionada
            app.OrthogonalExpansionDropDown.Value = char(app.selectedOrthogonalExpansion(app.selectedIndexFunction));
            app.configureOrthogonalExpansionDropDown;
            app.SeriesCoefficientsDropDown.Value = char(app.selectedSeriesCoefficients(app.selectedIndexFunction));

            % Faz as configurações relativa a periodicidade da função
            if app.isPeriodic(app.selectedIndexFunction)
                app.IsPeriodicSwitch.Value = 'Yes';
                app.PeriodicLamp.Color = 'green';
                app.HowmanyperiodsdoyouwanttoseeSpinnerLabel.Enable = true;
                app.PeriodsSpinner.Enable = true;
                app.PeriodsSpinner.Value = app.numberOfPeriods(app.selectedIndexFunction);
                app.IntervalLengthEditFieldLabel.Text = 'Period';
            else
                app.IsPeriodicSwitch.Value = 'No';
                app.PeriodicLamp.Color = 'red';
                app.HowmanyperiodsdoyouwanttoseeSpinnerLabel.Enable = false;
                app.PeriodsSpinner.Enable = false;
                app.PeriodsSpinner.Value = app.numberOfPeriods(app.selectedIndexFunction);
                app.IntervalLengthEditFieldLabel.Text = 'Interval Length';
            end  
            
            % Habilita o botão de limpar
            app.DeleteFunctionButton.Enable = true;
            
            % Se a função já foi gerada, carrega as informações na tela
            if app.functionGenerated(app.selectedIndexFunction)
                % Desabilita a caixa de texto do comprimento do intervalo e
                % armazena o valor que o usuário já tinha inserido
                app.IntervalLengthEditField.Enable = false;
                app.IntervalLengthEditField.Value = app.intervalLength(app.selectedIndexFunction);
                
                % Desabilita o botão de Apply
                app.ApplyButton.Enable = false;
                
                % Habilita o Slider e o configura com o número de termos
                % escolhido pelo usuário
                app.NumberOfTermsSlider.Enable = true;
                app.NumberOfTermsSlider.Value = app.N(app.selectedIndexFunction);
            
                % Carrega a imagem com a regra da função escolhida pelo usuário
                imageFileName = strcat('functionSegment', int2str(app.selectedIndexFunction), int2str(app.numberOfSegments(app.selectedIndexFunction)));
                path = strrep(fullfile(app.getcurrentdir(), imageFileName), '\', '/');
                app.FunctionRuleImage.ImageSource = strcat(path, '.png');
                
                % Constrói a função e seus coeficientes e desenha os gráficos
                % na tela
                app.buildFunction;
                app.buildOrthogonalExpansion;
                app.plotFunction;
                app.plotSpectrum;
            else
                % Desabilita a caixa de texto do comprimento do intervalo e
                % armazena o valor que o usuário já tinha inserido
                app.IntervalLengthEditFieldLabel.Enable = true;
                app.IntervalLengthEditField.Enable = true;
                app.IntervalLengthEditField.Value = app.intervalLength(app.selectedIndexFunction);
                
                % Desabilita o botão de Apply
                app.ApplyButton.Enable = true;
                
                % Habilita o Slider e o configura com o número de termos
                % escolhido pelo usuário
                app.NumberOfTermsSlider.Enable = false;
                app.NumberOfTermsSlider.Value = app.N(app.selectedIndexFunction);
                
                % Limpa ambos os gráficos
                app.clearAxes(0);
                app.clearAxes(1);
 
                % Remove a imagem com a regra da função
                app.FunctionRuleImage.ImageSource = '';
            end
  
        end
                  
        function plotBilateralSpectrum(app, amplitude)
            [amplitudeSpectrum, phaseSpectrum] = app.getBilateralSpectrum;

            if amplitude
                y = amplitudeSpectrum;
                yLabel = "$|c_{n}|$";
                titleLabel = 'Bilateral Amplitude Spectrum';
            else
                y = phaseSpectrum;
                yLabel = "$\angle{c_{n}}$";
                titleLabel = 'Bilateral Phase Spectrum';
            end
            
            if app.N(app.selectedIndexFunction) > 5
                n = -5:5;
                y(end - (app.N(app.selectedIndexFunction) - 6): end) = [];
                y(1:1+(app.N(app.selectedIndexFunction) - 6)) = [];
            else
                n = -app.N(app.selectedIndexFunction):app.N(app.selectedIndexFunction);
            end
            xLabel = strings(1, length(n));
            x = zeros(1, length(n));

            for k = 1:length(n)
                x(k) = n(k) *app.w0(app.selectedIndexFunction);
                xLabel(k) = strcat(string(n(k)), "\omega_{0}");
            end
            
            stem(app.CoefficientsAxes, n * app.w0(app.selectedIndexFunction), y, 'filled', 'MarkerSize', 6, 'Color', 'b');
            xlabel(app.CoefficientsAxes, '$n\omega_{0}$', 'FontSize', 16, 'interpreter', 'latex');
            ylabel(app.CoefficientsAxes, yLabel, 'FontSize', 16, 'interpreter', 'latex');
            title(app.CoefficientsAxes, titleLabel);
            xticks(app.CoefficientsAxes, x);
            xticklabels(app.CoefficientsAxes, xLabel); 
            view(app.CoefficientsAxes, 2);
        end

        function plot3DBesselCoefficients(app)
            L = length(app.besselCoefficients);
            step = 1;
            % Permite no máximo 6 coeficientes
            if L > 15
                step = 5;
                 if L > 40
                    L = 41;
                 end
            end
           
            onesVector = ones(1, length(app.functionDomain));
            alpha = besselzero(0, app.N(app.selectedIndexFunction) + 1);
            w = alpha / app.functionDomain(end);
            % Habilita o recurso quem mantém várias formas de onda no mesmo
            % plot
            hold(app.CoefficientsAxes, 'on');
            S = 0;
            for i = 1:L
                signal = app.besselCoefficients(i) * besselj(0, w(i) * app.functionDomain);
                h = plot3(app.CoefficientsAxes, app.functionDomain, (i - 1) * onesVector, signal);
                stem3(app.CoefficientsAxes, min(app.functionDomain), (i - 1), app.besselCoefficients(i), 'fill', 'MarkerSize', 3, 'Color', get(h, 'Color'));
                S = S + signal;
            end
            plot3(app.CoefficientsAxes, app.functionDomain, (L + 1) * onesVector, S, 'r'); 

            title(app.CoefficientsAxes, '3D Bessel Coefficients');
            xlabel(app.CoefficientsAxes,'x', 'interpreter', 'latex');
            ylabel(app.CoefficientsAxes,'$n$', 'FontSize', 16, 'interpreter', 'latex');
            yticks(app.CoefficientsAxes, [0:step:L-1 (L + 1)]);
            yticklabels(app.CoefficientsAxes, [string(0:step:L-1) "\Sigma"]);
            zlabel(app.CoefficientsAxes, '$J_0(\omega_nx)$', 'FontSize', 16, 'interpreter', 'latex');
            
            hold(app.CoefficientsAxes, 'off');
            view(app.CoefficientsAxes, -60, 35);
        end
        
        function plot3DLegendreCoefficients(app)
            L = length(app.legendreCoefficients);
             step = 1;
            % Permite no máximo 6 coeficientes
            if L > 15
                step = 5;
                 if L > 40
                    L = 41;
                 end
            end
            
            onesVector = ones(1, length(app.functionDomain));
            % Habilita o recurso quem mantém várias formas de onda no mesmo
            % plot
            hold(app.CoefficientsAxes, 'on');
            S = 0;
            P = legP(L-1, app.functionDomain, app.functionDomain(1), app.functionDomain(end), 1);
            for i = 1:L
                signal = app.legendreCoefficients(i) * P(:, i);
                plot3(app.CoefficientsAxes, app.functionDomain, (i - 1) * onesVector, signal);
                S = S + signal;
            end
            plot3(app.CoefficientsAxes, app.functionDomain, (L + 1) * onesVector, S, 'r'); 

            title(app.CoefficientsAxes, '3D Legendre Coefficients');
            xlabel(app.CoefficientsAxes,'x', 'interpreter', 'latex');
            ylabel(app.CoefficientsAxes,'$n$', 'FontSize', 16, 'interpreter', 'latex');
            yticks(app.CoefficientsAxes, [0:step:L-1 (L + 1)]);
            yticklabels(app.CoefficientsAxes, [string(0:step:L-1) "\Sigma"]);
            zlabel(app.CoefficientsAxes, '$P_n(x)$', 'FontSize', 16, 'interpreter', 'latex');
            
            hold(app.CoefficientsAxes, 'off');
            view(app.CoefficientsAxes, -60, 35);
            
            
        end
    
        function plot3DUnilateralSpectrum(app)  
            % Obtém os espectros unilaterais de amplitude e fase
            [amplitudeSpectrum, phaseSpectrum] = app.getUnilateralSpectrum;
            % Converte a fase de radianos para graus
            phaseSpectrum = phaseSpectrum * pi/180;
            
            % Habilita o recurso quem mantém várias formas de onda no mesmo
            % plot
            hold(app.CoefficientsAxes, 'on');

            % Obtém o número de coeficientes
            L = length(amplitudeSpectrum);
            
            % Permite no máximo 6 coeficientes
            step = 1;
            % Permite no máximo 6 coeficientes
            if L > 15
                step = 5;
                 if L > 20
                    L = 20;
                 end
            end
            
            onesVector = ones(1, length(app.functionDomain));
            
            % Efetua o plot tridimensional das senoides com as amplitudes e
            % fases dos espectros unilaterais
            S = 0;
            app.w0(app.selectedIndexFunction)
            for i = 1:L
                signal = amplitudeSpectrum(i) * cos((i - 1) * app.w0(app.selectedIndexFunction) * app.functionDomain + phaseSpectrum(i));
                h = plot3(app.CoefficientsAxes, app.functionDomain, (i - 1) * app.w0(app.selectedIndexFunction) * onesVector, signal);
                stem3(app.CoefficientsAxes, min(app.functionDomain), (i - 1) * app.w0(app.selectedIndexFunction), amplitudeSpectrum(i), 'fill', 'MarkerSize', 3, 'Color', get(h, 'Color'));
                S = S + signal;
            end
            plot3(app.CoefficientsAxes, app.functionDomain, (L + 1) * app.w0(app.selectedIndexFunction) * onesVector, S, 'r'); 
            
            
            if mod(L, step) ~= 0  
                yLabel = strings(1, (L-1)/step);
                y = zeros(1, (L-1)/step);
                upperValue = (L-1)/step +1;
            else
                  yLabel = strings(1, L/step);
                y = zeros(1, L/step);
                upperValue = L/step;
            end
            
            for k = 1:upperValue
                y(k) =  step*(k - 1) * app.w0(app.selectedIndexFunction);
                yLabel(k) = strcat(string((k - 1) * step), "\omega_{0}");
            end
        

            title(app.CoefficientsAxes, 'Unilateral Amplitude Spectrum (3D)');
            xlabel(app.CoefficientsAxes,'x', 'interpreter', 'latex');
            ylabel(app.CoefficientsAxes,'$n\omega_{0}$', 'FontSize', 16, 'interpreter', 'latex');
            yticks(app.CoefficientsAxes, [y (step * (upperValue + 1) * app.w0(app.selectedIndexFunction))]);
            yticklabels(app.CoefficientsAxes, [yLabel "\Sigma"]);
            zlabel(app.CoefficientsAxes, '$A_{n}\cos{(n\omega_{0}x + \phi_{n})}$', 'FontSize', 16, 'interpreter', 'latex');
            
            view(app.CoefficientsAxes, -70, 25);
            hold(app.CoefficientsAxes, 'off');
        end
        
        function plotUnilateralSpectrum(app, amplitude)
            
            [amplitudeSpectrum, phaseSpectrum] = app.getUnilateralSpectrum;

            if amplitude 
                y = amplitudeSpectrum;
                yLabel = "$A_{n}$";
                titleLabel = 'Unilateral Amplitude Spectrum';
            else
                y = phaseSpectrum;
                yLabel = "$\phi_{n}$";
                titleLabel = 'Unilateral Phase Spectrum';
            end
            
            if app.N(app.selectedIndexFunction) > 15
                y = y(1:16);
            end
            
            xLabel = strings(1, length(y));
            x = zeros(1, length(y));

            for k = 1:length(x)
                x(k) = (k - 1) * app.w0(app.selectedIndexFunction);
                xLabel(k) = strcat(string(k - 1), "\omega_{0}");
            end
            
            stem(app.CoefficientsAxes, x', y, 'filled', 'MarkerSize', 6, 'Color', 'b');
            xlabel(app.CoefficientsAxes, '$n\omega_{0}$', 'FontSize', 14, 'interpreter', 'latex');
            ylabel(app.CoefficientsAxes, yLabel, 'FontSize', 16, 'interpreter', 'latex');
            title(app.CoefficientsAxes, titleLabel);
            
            xticks(app.CoefficientsAxes, x);
            xticklabels(app.CoefficientsAxes, xLabel);
            view(app.CoefficientsAxes, 2);

        end

        function defaultState(app)
            
            % Faz a pré-alocação da matriz com as funções e os segmentos
            % que serão inseridos pelo usuário
            app.numberOfFunctions = 1;
            app.selectedIndexFunction = 1;
            app.functionsMatrix = struct('lowerLimit', 0, 'upperLimit', 0, 'functionDef', 0);
            app.numberOfSegments = zeros(1, app.numberOfFunctions);
            app.intervalLength = zeros(1, app.numberOfFunctions);
            app.w0 = zeros(1, app.numberOfFunctions);
            app.N = zeros(1, app.numberOfFunctions);
            app.numberOfPeriods = ones(1, app.numberOfFunctions);
            app.isPeriodic = zeros(1, app.numberOfFunctions);
            app.selectedOrthogonalExpansion = repmat("1", 1, app.numberOfFunctions);
            app.selectedSeriesCoefficients = repmat("1", 1, app.numberOfFunctions);
            app.functionGenerated = zeros(1, app.numberOfFunctions);
                
            % Coloca a janela no centro da tela
            movegui(app.FreyaUIFigure, 'center');
            
            % Desabilita todos os objetos do painel de configuração da
            % função
            set(findall(app.LeftPanel, '-property', 'enable'), 'enable', 'off');
            
            % Limpa todas as caixas de texto
            set(findall(app.LeftPanel, 'Type', 'uieditfield'), 'value', '');
            set(findall(app.LeftPanel, 'Type', 'uinumericeditfield'), 'value', 0);

            % Desabilita todos os objetos do painel de visualização da
            % série de Fourier
            set(findall(app.RightPanel, '-property', 'enable'), 'enable', 'off');
            
            % Habilita o botão que retorna as condições iniciais do
            % programa
            app.ClearButton.Enable = true;
            
            % Configura os objetos para que eles fiquem no estado inicial
            app.NumberofFunctionsSpinnerLabel.Enable = true;
            app.NumberofFunctionsSpinner.Enable = true;
            app.NumberofFunctionsSpinner.Value = 1;
            app.ConfirmButton.Enable = true;
            
            app.SelectedFunctionDropDown.Items = {};
            
            app.OrthogonalExpansionDropDown.Value = '1';
            app.configureOrthogonalExpansionDropDown;
            
            app.IsPeriodicSwitch.Value = 'No';
            app.PeriodicLamp.Color = 'red';
            app.PeriodsSpinner.Value = 1;
            
            app.FunctionRuleImage.ImageSource = '';
            
            app.NumberOfTermsSlider.Value = 0;
            
            app.clearAxes(0);
            app.clearAxes(1);
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.defaultState;
            
            if ~exist(fullfile(app.getcurrentdir()), 'dir')
                mkdir(fullfile(fullfile(app.getcurrentdir())))
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.FreyaUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {695, 695};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {392, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            lowerLim = app.LowerLimitEditField.Value;
            upperLim = app.UpperLimitEditField.Value;
            
            % O limite superior sempre deve ser maior que o limite inferior
            if lowerLim >= upperLim
                uialert(app.FreyaUIFigure, 'The upper limit must be greather than the lower limit.', 'Warning', 'Icon', 'warning');
                return
            end

            % Verifica se o intervalo inserido é maior que o máximo
            % informado pelo usuário
            if app.numberOfSegments(app.selectedIndexFunction) == 0
                if upperLim - lowerLim > app.intervalLength(app.selectedIndexFunction)
                    uialert(app.FreyaUIFigure, 'The length of the interval provided is greater than the maximum !', 'Warning', 'Icon', 'warning');
                    return
                end
            else
                if upperLim - app.functionsMatrix(app.selectedIndexFunction, 1).lowerLimit > app.intervalLength(app.selectedIndexFunction)
                    uialert(app.FreyaUIFigure, 'The length of the interval provided is greater than the maximum !', 'Warning', 'Icon', 'warning');
                    return
                end
            end
            
            % Remove o nome de funções existentes no matlab do texto
            % inserido pelo usuário
            functionText = app.FunctionxEditField.Value;
            previousFunctionText = functionText;
            strFunctions = ["abs", "log", "exp", "heaviside", "cos", "sin", "tan", "cot", "csc", "sec", "(", ")"];
            for i = 1:length(strFunctions)
                functionText = strrep(functionText, strFunctions(i), '');
            end
            
            % Verifica se há alguma letra que não seja x no texto inserido
            % pelo usuario (sem as funções)
            functionProblem = false;
            for k = 0:25
                if k~= 23 && any(char(functionText) == char('a' + k))
                    functionProblem = true;
                    break;
                end
                if any(char(functionText) == char('A' + k))
                    functionProblem = true;
                    break;
                end
            end

            if functionProblem
                uialert(app.FreyaUIFigure, 'The independent variable must be x !', 'Error', 'Icon', 'error');
                return
            end
            
            % Tenta converter o texto em uma função válida
            try
                funcDef = str2func(vectorize(strcat('@(x) x - x + ', previousFunctionText)));
            catch
                uialert(app.FreyaUIFigure, 'The supplied function cannot be converted into a valid function.', 'Error', 'Icon', 'error');
                return
            end
            
            % Contabiliza um segmento de função inserido          
            app.numberOfSegments(app.selectedIndexFunction) = app.numberOfSegments(app.selectedIndexFunction) + 1;
            
            % Armazena os limites do segmento da função juntamente com sua
            % definição no array de funções
            app.functionsMatrix(app.selectedIndexFunction, app.numberOfSegments(app.selectedIndexFunction)).lowerLimit = lowerLim;
            app.functionsMatrix(app.selectedIndexFunction, app.numberOfSegments(app.selectedIndexFunction)).upperLimit = upperLim;
            app.functionsMatrix(app.selectedIndexFunction, app.numberOfSegments(app.selectedIndexFunction)).functionDef = funcDef;

            % Gera o código LaTeX para a função inserida
            str = '';
            for k = 1:app.numberOfSegments(app.selectedIndexFunction)
                strFunction = replace(func2str(app.functionsMatrix(app.selectedIndexFunction, k).functionDef), '@(x)x-x+', '');
                strFunction = replace(strFunction, '.*', '');
                strFunction = replace(strFunction, './', '/');
                strFunction = replace(strFunction, '.^', '^');
                
                if k == app.numberOfSegments(app.selectedIndexFunction)
                    str = strcat(str, strFunction, ', &', string(app.functionsMatrix(app.selectedIndexFunction, k).lowerLimit), ' < x < ', string(app.functionsMatrix(app.selectedIndexFunction, k).upperLimit));
                else
                    str = strcat(str, strFunction, ', &', string(app.functionsMatrix(app.selectedIndexFunction, k).lowerLimit), ' < x < ', string(app.functionsMatrix(app.selectedIndexFunction, k).upperLimit), '\\');
                end
            end
            latexCodeString = strcat('$f(x) = \left\{ \begin{array}{cc}', str, '\end{array} \right.$');
            
            % Cria uma imagem com o código LaTeX e mostra na tela
            latexFigure = figure('visible', 'off');
            annotation(latexFigure, 'textbox', 'String', latexCodeString, 'Interpreter', 'latex', 'EdgeColor', 'none');
            imageFileName = strcat('functionSegment', int2str(app.selectedIndexFunction), int2str(app.numberOfSegments(app.selectedIndexFunction)));
            path = strrep(fullfile(app.getcurrentdir(), imageFileName), '\', '/');
            export_fig(path, '-dpng', '-transparent', '-r300'); 
            figure(app.FreyaUIFigure);
            app.FunctionRuleImage.ImageSource = strcat(path, '.png');
            close(latexFigure);
            
            if upperLim - app.functionsMatrix(app.selectedIndexFunction, 1).lowerLimit == app.intervalLength(app.selectedIndexFunction)
                
                app.functionGenerated(app.selectedIndexFunction) = 1;
                
                % Desabilita todos os objetos do painel que configura os
                % segmentos da função
                set(findall(app.FunctionSegmentPanel, '-property', 'enable'), 'enable', 'off');
                app.FunctionRuleImage.Enable = true;
                
                % Coloca o valor padrão em ambas as caixas de texto
                app.LowerLimitEditField.Value = 0;
                app.UpperLimitEditField.Value = 0;
     
                % Constrói a função com base nos segmentos e intervalos
                % informados pelo usuário
                app.buildFunction;
                app.buildOrthogonalExpansion;
                app.plotFunction;
                app.plotSpectrum;
                
                % Habilita o objeto que controla o número de termos da soma
                % parcial
                app.NumberofTermsLabel.Enable = true;
                app.NumberOfTermsSlider.Enable = true;
            else
                app.LowerLimitEditField.Enable = false;
                app.LowerLimitEditField.Value = upperLim;
                app.UpperLimitEditField.Value = 0;
            end
            
            app.FunctionxEditField.Value = '';

        end

        % Value changed function: NumberOfTermsSlider
        function NumberOfTermsSliderValueChanged(app, event)
            
            % Armazena o número de termos escolhido pelo usuário
            value = app.NumberOfTermsSlider.Value;
            
            % Identifica qual é o inteiro mais próximo entre os existentes
            [~, minIndex] = min(abs(value - event.Source.MinorTicks(:)));
            
            % Coloca o marcador no inteiro mais próximo identificado
            event.Source.Value = event.Source.MinorTicks(minIndex);
            
            % Armazena o número de termos da expansão em série
            app.N(app.selectedIndexFunction) = event.Source.MinorTicks(minIndex);
            
            % Atualiza o gráfico da função e da expansão com base no novo
            % número de termos
            app.buildOrthogonalExpansion;
            app.plotFunction;
            app.plotSpectrum;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            % Verifica se o tamanho do intervalo é nulo
            % Emite uma mensagem de aviso em caso afirmativo
            if app.IntervalLengthEditField.Value == 0
                uialert(app.FreyaUIFigure, 'The interval length must be greather than zero !', 'Warning', 'Icon', 'warning');
                return
            end
            
            % Armazena o comprimento do intervalo informado pelo usuário
            app.intervalLength(app.selectedIndexFunction) = app.IntervalLengthEditField.Value;
            app.w0(app.selectedIndexFunction) = 2 * pi / app.intervalLength(app.selectedIndexFunction);
            
            % Desabilita o botão Apply e a caixa de texto do comprimento do intervalo
            app.IntervalLengthEditFieldLabel.Enable = false;
            app.IntervalLengthEditField.Enable = false;
            app.ApplyButton.Enable = false;
            
            % Habilita todos os objetos do painel que configura os
            % segmentos da função
            set(findall(app.FunctionSegmentPanel, '-property', 'enable'), 'enable', 'on');
            
            if str2double(app.selectedOrthogonalExpansion(app.selectedIndexFunction)) == OrthogonalExpansions.FourierBesselSeries
                app.LowerLimitEditField.Enable = false;
                app.LowerLimitEditField.Value = 0;
            end
        end

        % Value changed function: IsPeriodicSwitch
        function IsPeriodicSwitchValueChanged(app, event)
            
            switch app.IsPeriodicSwitch.Value
                case 'Yes'
                    app.isPeriodic(app.selectedIndexFunction) = 1;
                    app.IntervalLengthEditFieldLabel.Text = 'Period';
                    app.HowmanyperiodsdoyouwanttoseeSpinnerLabel.Enable = true;
                    app.PeriodsSpinner.Enable = true;
                    app.PeriodicLamp.Color = 'g';
                case 'No'
                    app.isPeriodic(app.selectedIndexFunction) = 0;
                    app.IntervalLengthEditFieldLabel.Text = 'Interval Length';
                    app.HowmanyperiodsdoyouwanttoseeSpinnerLabel.Enable = false;
                    app.PeriodsSpinner.Enable = false;
                    app.PeriodicLamp.Color = 'r';
                    app.PeriodsSpinner.Value = 1;
                    app.numberOfPeriods(app.selectedIndexFunction) = 1;
                    if app.functionGenerated(app.selectedIndexFunction)
                       % app.buildPeriodicFunction;
                        app.buildFunction;
                        app.buildOrthogonalExpansion;
                        app.plotFunction;
                        app.plotSpectrum;
                    end
            end
        end

        % Value changed function: PeriodsSpinner
        function PeriodsSpinnerValueChanged(app, event)
            app.numberOfPeriods(app.selectedIndexFunction) = app.PeriodsSpinner.Value;

            if  app.functionGenerated(app.selectedIndexFunction)
                app.buildPeriodicFunction;
                app.buildOrthogonalExpansion;
                app.plotFunction;
                app.plotSpectrum;
            end
        end

        % Value changed function: OrthogonalExpansionDropDown
        function OrthogonalExpansionDropDownValueChanged(app, event)

           if app.numberOfSegments(app.selectedIndexFunction) > 0 && app.functionsMatrix(app.selectedIndexFunction, 1).lowerLimit ~= 0 && str2double(app.OrthogonalExpansionDropDown.Value) == OrthogonalExpansions.FourierBesselSeries
                uialert(app.FreyaUIFigure, "You can't use the Fourier-Bessel Series Expansion for this interval !", 'Warning', 'Icon', 'warning');
                app.OrthogonalExpansionDropDown.Value = app.selectedOrthogonalExpansion(app.selectedIndexFunction);
                return
           end
      
           app.selectedOrthogonalExpansion(app.selectedIndexFunction) = app.OrthogonalExpansionDropDown.Value;
           app.configureOrthogonalExpansionDropDown;
           app.selectedSeriesCoefficients(app.selectedIndexFunction) = '1';
           app.SeriesCoefficientsDropDown.Value = '1';
           
          if app.functionGenerated(app.selectedIndexFunction)
                app.buildOrthogonalExpansion;
                app.plotFunction;
                app.plotSpectrum;
          else  
                if app.numberOfSegments(app.selectedIndexFunction) == 0 && app.ApplyButton.Enable == false
                    app.LowerLimitEditField.Enable = true;
                end
                
                if app.numberOfSegments(app.selectedIndexFunction) == 0 && str2double(app.selectedOrthogonalExpansion(app.selectedIndexFunction)) == OrthogonalExpansions.FourierBesselSeries
                    app.LowerLimitEditField.Enable = false;
                    app.LowerLimitEditField.Value = 0;
                end
          end
        end

        % Callback function
        function FreyaUIFigureKeyPress(app, event)
    

        end

        % Key release function: FreyaUIFigure
        function FreyaUIFigureKeyRelease(app, event)


        end

        % Button pushed function: DeleteFunctionButton
        function DeleteFunctionButtonPushed(app, event)

            app.clearCurrentFunction;           
        end

        % Window key release function: FreyaUIFigure
        function FreyaUIFigureWindowKeyRelease(app, event)
          
        end

        % Value changed function: SeriesCoefficientsDropDown
        function SeriesCoefficientsDropDownValueChanged(app, event)

            app.selectedSeriesCoefficients(app.selectedIndexFunction) = app.SeriesCoefficientsDropDown.Value;
            if app.functionGenerated(app.selectedIndexFunction)
                app.plotSpectrum;
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.numberOfFunctions = app.NumberofFunctionsSpinner.Value;
            
            % Desabilita o botão de confirmar e o Spinner com o número de
            % funções
            app.NumberofFunctionsSpinnerLabel.Enable = false;
            app.NumberofFunctionsSpinner.Enable = false;
            app.ConfirmButton.Enable = false;
            
            % Habilita o menu suspenso para escolher a função que será
            % configurada
            app.SelectedFunctionDropDownLabel.Enable = true;
            app.SelectedFunctionDropDown.Enable = true;
            app.SelectedFunctionDropDown.Items = string(1:app.numberOfFunctions);
            
            app.OrthogonalExpansionLabel.Enable = true;
            app.OrthogonalExpansionDropDown.Enable = true;
            app.SeriesCoefficientsDropDownLabel.Enable = true;
            app.SeriesCoefficientsDropDown.Enable = true;
                       
            app.IsPeriodicSwitch.Enable = true;
            app.IntervalLengthEditFieldLabel.Enable = true;
            app.IntervalLengthEditField.Enable = true;
            app.ApplyButton.Enable = true;
            app.DeleteFunctionButton.Enable = true;
            app.PeriodicLamp.Enable = true;
                      
            % Faz a pré-alocação da matriz com as funções e os segmentos
            % que serão inseridos pelo usuário
            app.functionsMatrix(app.numberOfFunctions, 100) = struct('lowerLimit', [], 'upperLimit', [], 'functionDef', []);
            app.numberOfSegments = zeros(1, app.numberOfFunctions);
            app.intervalLength = zeros(1, app.numberOfFunctions);
            app.w0 = zeros(1, app.numberOfFunctions);
            app.N = zeros(1, app.numberOfFunctions);
            app.numberOfPeriods = ones(1, app.numberOfFunctions);
            app.isPeriodic = zeros(1, app.numberOfFunctions);
            app.selectedOrthogonalExpansion = repmat("1", 1, app.numberOfFunctions);
            app.selectedSeriesCoefficients = repmat("1", 1, app.numberOfFunctions);
            app.functionGenerated = zeros(1, app.numberOfFunctions);
            
            app.configureOrthogonalExpansionDropDown;
        end

        % Value changed function: SelectedFunctionDropDown
        function SelectedFunctionDropDownValueChanged(app, event)

            % Armazena o índice da função selecionada
            app.selectedIndexFunction = str2double(app.SelectedFunctionDropDown.Value);
            app.loadSelectedFunction;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.defaultState;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FreyaUIFigure = uifigure('Visible', 'off');
            app.FreyaUIFigure.Name = 'Freya';
            app.FreyaUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FreyaUIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @FreyaUIFigureWindowKeyRelease, true);
            app.FreyaUIFigure.KeyReleaseFcn = createCallbackFcn(app, @FreyaUIFigureKeyRelease, true);
            app.GridLayout = uigridlayout(app.FreyaUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Function Configuration';
            app.OrthogonalExpansionLabel = uilabel(app.LeftPanel);
            app.OrthogonalExpansionLabel.Text = {'Orthogonal Expansion'; ''};
            app.OrthogonalExpansionDropDown = uidropdown(app.LeftPanel);
            app.OrthogonalExpansionDropDown.Items = {'Fourier Series', 'Fourier-Legendre Series', 'Fourier-Bessel Series'};
            app.OrthogonalExpansionDropDown.ItemsData = {'1', '2', '3'};
            app.OrthogonalExpansionDropDown.ValueChangedFcn = createCallbackFcn(app, @OrthogonalExpansionDropDownValueChanged, true);
            app.OrthogonalExpansionDropDown.Value = '1';
            app.IsaperiodicfunctionSwitchLabel = uilabel(app.LeftPanel);
            app.IsaperiodicfunctionSwitchLabel.Text = {'Is a periodic function ?'; ''};
            app.IsPeriodicSwitch = uiswitch(app.LeftPanel, 'slider');
            app.IsPeriodicSwitch.Items = {'No', 'Yes'};
            app.IsPeriodicSwitch.ValueChangedFcn = createCallbackFcn(app, @IsPeriodicSwitchValueChanged, true);
            app.IsPeriodicSwitch.Value = 'No';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {''; ''; ''};
            app.PeriodicLamp = uilamp(app.LeftPanel);
            app.HowmanyperiodsdoyouwanttoseeSpinnerLabel = uilabel(app.LeftPanel);
            app.HowmanyperiodsdoyouwanttoseeSpinnerLabel.Enable = 'off';
            app.HowmanyperiodsdoyouwanttoseeSpinnerLabel.Text = 'How many periods do you want to see?';
            app.PeriodsSpinner = uispinner(app.LeftPanel);
            app.PeriodsSpinner.Limits = [1 10];
            app.PeriodsSpinner.ValueChangedFcn = createCallbackFcn(app, @PeriodsSpinnerValueChanged, true);
            app.PeriodsSpinner.Enable = 'off';
            app.PeriodsSpinner.Value = 1;
            app.IntervalLengthEditFieldLabel = uilabel(app.LeftPanel);
            app.IntervalLengthEditFieldLabel.Text = 'Interval Length';
            app.IntervalLengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalLengthEditField.Limits = [0 10];
            app.FunctionSegmentPanel = uipanel(app.LeftPanel);
            app.FunctionSegmentPanel.Title = 'Function Segment';
            app.LowerLimitEditFieldLabel = uilabel(app.FunctionSegmentPanel);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.FunctionSegmentPanel, 'numeric');
            app.UpperLimitEditFieldLabel = uilabel(app.FunctionSegmentPanel);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.FunctionSegmentPanel, 'numeric');
            app.FunctionxEditFieldLabel = uilabel(app.FunctionSegmentPanel);
            app.FunctionxEditFieldLabel.Text = 'Function (x)';
            app.FunctionxEditField = uieditfield(app.FunctionSegmentPanel, 'text');
            app.AddButton = uibutton(app.FunctionSegmentPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.FunctionRuleImage = uiimage(app.FunctionSegmentPanel);
            app.ApplyButton = uibutton(app.LeftPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DeleteFunctionButton = uibutton(app.LeftPanel, 'push');
            app.DeleteFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFunctionButtonPushed, true);
            app.DeleteFunctionButton.Text = 'Delete Function';
            app.SeriesCoefficientsDropDownLabel = uilabel(app.LeftPanel);
            app.SeriesCoefficientsDropDownLabel.Text = {'Series Coefficients'; ''};
            app.SeriesCoefficientsDropDown = uidropdown(app.LeftPanel);
            app.SeriesCoefficientsDropDown.Items = {};
            app.SeriesCoefficientsDropDown.ValueChangedFcn = createCallbackFcn(app, @SeriesCoefficientsDropDownValueChanged, true);
            app.SeriesCoefficientsDropDown.Value = {};
            app.SelectedFunctionDropDownLabel = uilabel(app.LeftPanel);
            app.SelectedFunctionDropDownLabel.Text = 'Selected Function';
            app.SelectedFunctionDropDown = uidropdown(app.LeftPanel);
            app.SelectedFunctionDropDown.Items = {};
            app.SelectedFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedFunctionDropDownValueChanged, true);
            app.SelectedFunctionDropDown.Value = {};
            app.ConfirmButton = uibutton(app.LeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.NumberofFunctionsSpinnerLabel = uilabel(app.LeftPanel);
            app.NumberofFunctionsSpinnerLabel.Text = 'Number of Functions';
            app.NumberofFunctionsSpinner = uispinner(app.LeftPanel);
            app.NumberofFunctionsSpinner.Limits = [1 10];
            app.NumberofFunctionsSpinner.RoundFractionalValues = 'on';
            app.NumberofFunctionsSpinner.Value = 1;
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Fourier Series Visualization';
            app.FourierSeriesAxes = uiaxes(app.RightPanel);
            title(app.FourierSeriesAxes, {''; ''})
            app.CoefficientsAxes = uiaxes(app.RightPanel);
            app.NumberofTermsLabel = uilabel(app.RightPanel);
            app.NumberofTermsLabel.Text = 'Number of Terms';
            app.NumberOfTermsSlider = uislider(app.RightPanel);
            app.NumberOfTermsSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.NumberOfTermsSlider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60', '65', '70', '75', '80', '85', '90', '95', '100'};
            app.NumberOfTermsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberOfTermsSliderValueChanged, true);
            app.NumberOfTermsSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            % Show the figure after all components are created
            app.FreyaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FourierSeries_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FreyaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FreyaUIFigure)
        end
    end
end